classdef MEA_Analysis_Toolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MEA_Analysis_ToolboxUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OpenfileTab                   matlab.ui.container.Tab
        UIAxes                        matlab.ui.control.UIAxes
        Label                         matlab.ui.control.Label
        OpenfileButton                matlab.ui.control.StateButton
        MEAlayout60MEA20030iRButtonGroup  matlab.ui.container.ButtonGroup
        Button21                      matlab.ui.control.RadioButton
        Button22                      matlab.ui.control.RadioButton
        Button23                      matlab.ui.control.RadioButton
        Button24                      matlab.ui.control.RadioButton
        Button25                      matlab.ui.control.RadioButton
        Button26                      matlab.ui.control.RadioButton
        Button27                      matlab.ui.control.RadioButton
        Button31                      matlab.ui.control.RadioButton
        Button32                      matlab.ui.control.RadioButton
        Button33                      matlab.ui.control.RadioButton
        Button34                      matlab.ui.control.RadioButton
        Button35                      matlab.ui.control.RadioButton
        Button36                      matlab.ui.control.RadioButton
        Button37                      matlab.ui.control.RadioButton
        Button38                      matlab.ui.control.RadioButton
        Button41                      matlab.ui.control.RadioButton
        Button42                      matlab.ui.control.RadioButton
        Button43                      matlab.ui.control.RadioButton
        Button44                      matlab.ui.control.RadioButton
        Button45                      matlab.ui.control.RadioButton
        Button46                      matlab.ui.control.RadioButton
        Button47                      matlab.ui.control.RadioButton
        Button48                      matlab.ui.control.RadioButton
        Button51                      matlab.ui.control.RadioButton
        Button52                      matlab.ui.control.RadioButton
        Button53                      matlab.ui.control.RadioButton
        Button54                      matlab.ui.control.RadioButton
        Button55                      matlab.ui.control.RadioButton
        Button56                      matlab.ui.control.RadioButton
        Button57                      matlab.ui.control.RadioButton
        Button58                      matlab.ui.control.RadioButton
        Button61                      matlab.ui.control.RadioButton
        Button62                      matlab.ui.control.RadioButton
        Button63                      matlab.ui.control.RadioButton
        Button64                      matlab.ui.control.RadioButton
        Button65                      matlab.ui.control.RadioButton
        Button66                      matlab.ui.control.RadioButton
        Button67                      matlab.ui.control.RadioButton
        Button68                      matlab.ui.control.RadioButton
        Button71                      matlab.ui.control.RadioButton
        Button72                      matlab.ui.control.RadioButton
        Button73                      matlab.ui.control.RadioButton
        Button74                      matlab.ui.control.RadioButton
        Button75                      matlab.ui.control.RadioButton
        Button76                      matlab.ui.control.RadioButton
        Button77                      matlab.ui.control.RadioButton
        Button78                      matlab.ui.control.RadioButton
        Button82                      matlab.ui.control.RadioButton
        Button83                      matlab.ui.control.RadioButton
        Button84                      matlab.ui.control.RadioButton
        Button85                      matlab.ui.control.RadioButton
        Button86                      matlab.ui.control.RadioButton
        Button87                      matlab.ui.control.RadioButton
        Button12                      matlab.ui.control.RadioButton
        Button13                      matlab.ui.control.RadioButton
        Button14                      matlab.ui.control.RadioButton
        Button_ref                    matlab.ui.control.RadioButton
        Button16                      matlab.ui.control.RadioButton
        Button17                      matlab.ui.control.RadioButton
        Button28                      matlab.ui.control.RadioButton
        AnalogDataPlot                matlab.ui.control.UIAxes
        FigureButtonSpike             matlab.ui.control.Button
        FigureButtonAnalog            matlab.ui.control.Button
        Panel                         matlab.ui.container.Panel
        TsSliderLabel                 matlab.ui.control.Label
        TsSlider                      matlab.ui.control.Slider
        PCATab                        matlab.ui.container.Tab
        PCAonSpikeCutoutsPanel        matlab.ui.container.Panel
        Button                        matlab.ui.control.Button
        PCA_plot1                     matlab.ui.control.UIAxes
        Label_2                       matlab.ui.control.Label
        PCADropDown1                  matlab.ui.control.DropDown
        PCA_plot2                     matlab.ui.control.UIAxes
        DropDown_2Label               matlab.ui.control.Label
        PCADropDown2                  matlab.ui.control.DropDown
        Tab3                          matlab.ui.container.Tab
    end


    properties (Access = private)
        fileName % Description
        labels
        analogData
        spikeCuts
        dataFile
        chanIndex
        recDuration
        recStartTime
    end

    methods (Access = private)
    
        function [] = plotAnalog(app)
            if ~isempty(app.analogData)
                data = app.analogData.ChannelData(app.chanIndex,:)./1000000; 
                %data = app.analogData.ChannelData./1000000; 
                time = McsHDF5.TickToSec(app.analogData.ChannelDataTimeStamps);
                plot(app.AnalogDataPlot,time,data );
                app.AnalogDataPlot.XLim = [time(1) time(end)];
            end
        end
        
        function [] = plotSpikeCut(app)
            if ~isempty(app.spikeCuts)
                if ~isempty(app.spikeCuts.SegmentData{1,1})
                    data = app.spikeCuts.SegmentData{1,app.chanIndex}./1000000;
                    %data = app.spikeCuts.SegmentData{1,1}./1000000;
                    time =0:0.1:3;
                    plot(app.UIAxes,time, data);
                  
                else
                    plot(app.UIAxes,0:0.1:3,zeros(1,31) );
                end    
            end
        end

        function loadAnalogData(app)
            cfg = [];
            %cfg.channel = [app.chanIndex app.chanIndex]; % channel index ranging from i to j (1-60)
            cfg.window = [app.TsSlider.Value, app.TsSlider.Value+10]; % time range in seconds
            if ~isempty(app.dataFile.Recording{1}.AnalogStream)
                app.analogData = app.dataFile.Recording{1}.AnalogStream{1}.readPartialChannelData(cfg);
            end
        end
        
        function loadSpikeCuts(app)
            cfg = [];
            %cfg.segment = [app.chanIndex ]; % channel index ranging from i to j (1-60)
            if ~ isempty(app.dataFile.Recording{1}.SegmentStream)
                app.spikeCuts = app.dataFile.Recording{1}.SegmentStream{1}.readPartialSegmentData(cfg);
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function init(app)
            app.recDuration = 10;
            app.recStartTime = 0;
            app.chanIndex = 1;
            app.TsSlider.Value = 0;
            app.TsSlider.Limits = [0 app.recDuration];
            app.MEAlayout60MEA20030iRButtonGroup.SelectedObject = app.Button47;
            
            app.UIAxes.XLim = [0 3];
            app.UIAxes.XLabel.String = 'Time [ms]';
            app.UIAxes.YLabel.String = 'Voltage [\muV]';
            
            app.AnalogDataPlot.XLabel.String = 'Time [s]';
            app.AnalogDataPlot.YLabel.String = 'Voltage [\muV]';
            
            app.PCA_plot1.YLim = [0 100];
            app.PCA_plot1.XLabel.String = 'Principal components';
            app.PCA_plot1.YLabel.String = 'Explained variance [%]';
      
        end

        % Value changed function: OpenfileButton
        function OpenfileButtonValueChanged(app, event)
            value = app.OpenfileButton.Value;
            if value
               [app.fileName, filePath] = uigetfile('*.h5','Select HDF5 file with recording');
                if app.fileName
                    app.Label.Text = app.fileName;
                    cfg = [];
                    cfg.dataType = 'single'; 
                    app.dataFile = McsHDF5.McsData([filePath app.fileName],cfg);
                    app.recDuration = app.dataFile.Recording{1,1}.Duration/1000000;
                    disp(app.recDuration)
                    app.TsSlider.Limits = [0 301];
                    %chanLabel = app.MEAlayout60MEA20030iRButtonGroup.SelectedObject.Text;
                    %app.chanIndex = find(contains(app.analogData.Info.Label,chanLabel,'IgnoreCase',true));
                    app.chanIndex =1;
                    loadAnalogData(app);
                    loadSpikeCuts(app);
                    if ~ isempty(app.spikeCuts)
                        plotSpikeCut(app);
                    end
                    if ~ isempty(app.analogData)
                        plotAnalog(app);
                    end
                end
                app.OpenfileButton.Value = 0;
            end
            
        end

        % Value changed function: TsSlider
        function analogDataTimeSlider(app, event)
            value = app.TsSlider.Value;
            loadAnalogData(app);
            plotAnalog(app);
            
        end

        % Button pushed function: FigureButtonAnalog
        function plotAnalogFigure(app, event)
            if ~isempty(app.analogData)
                figure;
                cfg = [];
                cfg.window = [];
                cfg.channel = [app.chanIndex];
                plot(app.analogData, cfg);
            end
        end

        % Button pushed function: FigureButtonSpike
        function plotSpikeFigure(app, event)
            if ~isempty(app.spikeCuts)
                figure;
                cfg = [];
                cfg.window = [];
                cfg.segment = [app.chanIndex];
                plot(app.spikeCuts, cfg);
            end
        end

        % Button pushed function: Button
        function runPCASpikeCutout(app, event)
            [coeff,score,latent,tsquared,explained,mu] = pca(cell2mat(app.spikeCuts.SegmentData));
            for i = 2:length(explained)
                explained(i) = explained(i)+explained(i-1);
            end
            plot(app.PCA_plot1,explained );
            app.PCA_plot1.YLim = [0 100];
            app.PCA_plot1.XLim = [1 20];
            app.PCA_plot1.XLabel.String = 'Principal components';
            app.PCA_plot1.YLabel.String = 'Explained variance [%]';
            app.PCA_plot1.Title.String = 'Explained variance';
            scatter(app.PCA_plot2, coeff(:,1),coeff(:,2))
            app.PCA_plot2.XLabel.String = 'PC-1';
            app.PCA_plot2.YLabel.String = 'PC-2';
            app.PCA_plot2.Title.String = 'Loadings';
            %plot(app.PCA_plot2,coeff(:,1),coeff(:,2),'*');
        end

        % Selection changed function: MEAlayout60MEA20030iRButtonGroup
        function selectChan(app, event)
            selectedButton = app.MEAlayout60MEA20030iRButtonGroup.SelectedObject;
            if ~ isempty(app.dataFile)
                label = selectedButton.Text
                size(app.analogData.Info.Label)
                app.chanIndex = getLabelIndex({label},app.analogData.Info.Label);
                disp(app.chanIndex)
                
                %loadAnalogData(app);
                %loadSpikeCuts(app);
                plotSpikeCut(app);
                plotAnalog(app);
                
            end
      
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MEA_Analysis_ToolboxUIFigure = uifigure;
            app.MEA_Analysis_ToolboxUIFigure.Name = 'MEA Analysis Toolbox';
            app.TabGroup = uitabgroup(app.MEA_Analysis_ToolboxUIFigure);
            app.OpenfileTab = uitab(app.TabGroup);
            app.OpenfileTab.Title = 'Open file';
            app.UIAxes = uiaxes(app.OpenfileTab);
            title(app.UIAxes, 'Spike Cutout')
            xlabel(app.UIAxes, 'Time [ms]')
            ylabel(app.UIAxes, 'Voltage  V')
            app.Label = uilabel(app.OpenfileTab);
            app.Label.Text = '';
            app.OpenfileButton = uibutton(app.OpenfileTab, 'state');
            app.OpenfileButton.ValueChangedFcn = createCallbackFcn(app, @OpenfileButtonValueChanged, true);
            app.OpenfileButton.Text = 'Open file';
            app.MEAlayout60MEA20030iRButtonGroup = uibuttongroup(app.OpenfileTab);
            app.MEAlayout60MEA20030iRButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @selectChan, true);
            app.MEAlayout60MEA20030iRButtonGroup.Title = 'MEA layout: 60MEA200/30iR';
            app.Button21 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button21.Text = '21';
            app.Button22 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button22.Text = '22';
            app.Button23 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button23.Text = '23';
            app.Button24 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button24.Text = '24';
            app.Button25 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button25.Text = '25';
            app.Button26 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button26.Text = '26';
            app.Button27 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button27.Text = '27';
            app.Button31 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button31.Text = '31';
            app.Button32 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button32.Text = '32';
            app.Button33 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button33.Text = '33';
            app.Button34 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button34.Text = '34';
            app.Button35 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button35.Text = '35';
            app.Button36 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button36.Text = '36';
            app.Button37 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button37.Text = '37';
            app.Button38 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button38.Text = '38';
            app.Button41 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button41.Text = '41';
            app.Button42 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button42.Text = '42';
            app.Button43 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button43.Text = '43';
            app.Button44 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button44.Text = '44';
            app.Button45 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button45.Text = '45';
            app.Button46 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button46.Text = '46';
            app.Button47 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button47.Text = '47';
            app.Button48 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button48.Text = '48';
            app.Button51 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button51.Text = '51';
            app.Button52 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button52.Text = '52';
            app.Button53 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button53.Text = '53';
            app.Button54 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button54.Text = '54';
            app.Button55 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button55.Text = '55';
            app.Button56 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button56.Text = '56';
            app.Button57 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button57.Text = '57';
            app.Button58 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button58.Text = '58';
            app.Button61 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button61.Text = '61';
            app.Button62 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button62.Text = '62';
            app.Button63 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button63.Text = '63';
            app.Button64 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button64.Text = '64';
            app.Button65 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button65.Text = '65';
            app.Button66 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button66.Text = '66';
            app.Button67 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button67.Text = '67';
            app.Button68 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button68.Text = '68';
            app.Button71 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button71.Text = '71';
            app.Button72 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button72.Text = '72';
            app.Button73 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button73.Text = '73';
            app.Button74 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button74.Text = '74';
            app.Button75 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button75.Text = '75';
            app.Button76 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button76.Text = '76';
            app.Button77 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button77.Text = '77';
            app.Button78 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button78.Text = '78';
            app.Button82 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button82.Text = '82';
            app.Button83 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button83.Text = '83';
            app.Button84 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button84.Text = '84';
            app.Button85 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button85.Text = '85';
            app.Button86 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button86.Text = '86';
            app.Button87 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button87.Text = '87';
            app.Button12 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button12.Text = '12';
            app.Button13 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button13.Text = '13';
            app.Button14 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button14.Text = '14';
            app.Button_ref = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button_ref.Text = 'ref';
            app.Button_ref.Value = true;
            app.Button16 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button16.Text = '16';
            app.Button17 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button17.Text = '17';
            app.Button28 = uiradiobutton(app.MEAlayout60MEA20030iRButtonGroup);
            app.Button28.Text = '28';
            app.AnalogDataPlot = uiaxes(app.OpenfileTab);
            title(app.AnalogDataPlot, 'Analog data')
            xlabel(app.AnalogDataPlot, 'Time [s]')
            ylabel(app.AnalogDataPlot, 'Voltage 10^-6 V')
            app.FigureButtonSpike = uibutton(app.OpenfileTab, 'push');
            app.FigureButtonSpike.ButtonPushedFcn = createCallbackFcn(app, @plotSpikeFigure, true);
            app.FigureButtonSpike.Text = 'Figure';
            app.FigureButtonAnalog = uibutton(app.OpenfileTab, 'push');
            app.FigureButtonAnalog.ButtonPushedFcn = createCallbackFcn(app, @plotAnalogFigure, true);
            app.FigureButtonAnalog.Text = 'Figure';
            app.Panel = uipanel(app.OpenfileTab);
            app.Panel.Title = 'Panel';
            app.TsSliderLabel = uilabel(app.OpenfileTab);
            app.TsSliderLabel.Text = 'T [s]';
            app.TsSlider = uislider(app.OpenfileTab);
            app.TsSlider.Limits = [0 1000];
            app.TsSlider.ValueChangedFcn = createCallbackFcn(app, @analogDataTimeSlider, true);
            app.TsSlider.MinorTicks = [];
            app.PCATab = uitab(app.TabGroup);
            app.PCATab.Title = 'PCA';
            app.PCAonSpikeCutoutsPanel = uipanel(app.PCATab);
            app.PCAonSpikeCutoutsPanel.Title = 'PCA on Spike Cutouts';
            app.Button = uibutton(app.PCAonSpikeCutoutsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @runPCASpikeCutout, true);
            app.PCA_plot1 = uiaxes(app.PCATab);
            title(app.PCA_plot1, 'Title')
            xlabel(app.PCA_plot1, 'X')
            ylabel(app.PCA_plot1, 'Y')
            app.Label_2 = uilabel(app.PCATab);
            app.Label_2.Text = '';
            app.PCADropDown1 = uidropdown(app.PCATab);
            app.PCADropDown1.Items = {'Explained Variance', 'Scores', 'Influence', 'Loadings'};
            app.PCADropDown1.Value = 'Explained Variance';
            app.PCA_plot2 = uiaxes(app.PCATab);
            title(app.PCA_plot2, 'Title')
            xlabel(app.PCA_plot2, 'X')
            ylabel(app.PCA_plot2, 'Y')
            app.DropDown_2Label = uilabel(app.PCATab);
            app.DropDown_2Label.Text = '';
            app.PCADropDown2 = uidropdown(app.PCATab);
            app.PCADropDown2.Items = {'Explained Variance', 'Scores', 'Influence', 'Loadings'};
            app.PCADropDown2.Value = 'Loadings';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MEA_Analysis_Toolbox

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MEA_Analysis_ToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MEA_Analysis_ToolboxUIFigure)
        end
    end
end